classdef AdminPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Label                         matlab.ui.control.Label
        LogoutButton                  matlab.ui.control.Button
        RunningInterviewsButton       matlab.ui.control.Button
        AllCandidatesDetailsButton    matlab.ui.control.Button
        InterviewerDetailsButton      matlab.ui.control.Button
        UploadCandidatesDetailButton  matlab.ui.control.Button
        PassedCandidatesButton        matlab.ui.control.Button
        EvaluationButton              matlab.ui.control.Button
        CandidateDetailsButton        matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
        AddRemoveAdminButton          matlab.ui.control.Button
        AdminDashboardLabel           matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        Username=""; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CandidateDetailsButton
        function CandidateDetailsNextPage(app, event)
            AdminPageCandidatedetails
            app.UIFigure.Visible="off";
        end

        % Button pushed function: EvaluationButton
        function EvaluationNextPage(app, event)
            AdminPageEvaluation
            app.UIFigure.Visible="off";
        end

        % Button pushed function: InterviewerDetailsButton
        function InterviewerDetailsNextPage(app, event)
            AdminPageInterviewerdetails
            app.UIFigure.Visible="off";
        end

        % Button pushed function: RunningInterviewsButton
        function RunninginterviewsNextPage(app, event)
            AdminPageRunninginterviews
            app.UIFigure.Visible="off";
        end

        % Button pushed function: AddRemoveAdminButton
        function AddAdminNextPage(app, event)
            objj = AdminPageAddAdmin;
            objj.Username=app.Username;
            objj
            app.UIFigure.Visible="off";
        end

        % Button pushed function: AllCandidatesDetailsButton
        function AllCandidatesDetailsNextPage(app, event)
            app.UIFigure.Visible="off";
            objj = AdminPageAllCandidatesDetails;
            [E,objj.UITable.Data] = getAllCandidates();
            if E==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="No data available";
            else
                app.Label.Text="";
            end
            objj
            app.UIFigure.Visible="off";
        end

        % Button pushed function: PassedCandidatesButton
        function FinalResultNextPage(app, event)
            objj = AdminPageFinalResult;
            [E,objj.UITable.Data] = getPassedCandidates();
            if E==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text ="No data available";
            else
                app.UIFigure.Visible="off";
                objj
            end
        end

        % Button pushed function: UploadCandidatesDetailButton
        function UploadCandidateDetailsNextPage(app, event)
            [file,path]=uigetfile;
            disp(file);
            err=importCSV([path file]);
            if err=="Successfully Added"
                app.Label.FontColor=[0 1 0];
                app.Label.Text=err;
            else
                app.Label.FontColor=[1 0 0];
                app.Label.Text=err;
            end
        end

        % Button pushed function: LogoutButton
        function logoutAdmin(app, event)
            LoginPage;
            app.UIFigure.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.AdminDashboardLabel = uilabel(app.GridLayout);
            app.AdminDashboardLabel.Text = 'Admin Dashboard';
            app.AddRemoveAdminButton = uibutton(app.GridLayout, 'push');
            app.AddRemoveAdminButton.ButtonPushedFcn = createCallbackFcn(app, @AddAdminNextPage, true);
            app.AddRemoveAdminButton.Text = 'Add/Remove Admin';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.CandidateDetailsButton = uibutton(app.GridLayout, 'push');
            app.CandidateDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @CandidateDetailsNextPage, true);
            app.CandidateDetailsButton.Text = 'Candidate Details';
            app.EvaluationButton = uibutton(app.GridLayout, 'push');
            app.EvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluationNextPage, true);
            app.EvaluationButton.Text = 'Evaluation';
            app.PassedCandidatesButton = uibutton(app.GridLayout, 'push');
            app.PassedCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @FinalResultNextPage, true);
            app.PassedCandidatesButton.Text = 'Passed Candidates';
            app.UploadCandidatesDetailButton = uibutton(app.GridLayout, 'push');
            app.UploadCandidatesDetailButton.ButtonPushedFcn = createCallbackFcn(app, @UploadCandidateDetailsNextPage, true);
            app.UploadCandidatesDetailButton.Text = 'Upload Candidates Detail ';
            app.InterviewerDetailsButton = uibutton(app.GridLayout, 'push');
            app.InterviewerDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerDetailsNextPage, true);
            app.InterviewerDetailsButton.Text = 'Interviewer Details';
            app.AllCandidatesDetailsButton = uibutton(app.GridLayout, 'push');
            app.AllCandidatesDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @AllCandidatesDetailsNextPage, true);
            app.AllCandidatesDetailsButton.Text = 'All Candidates Details';
            app.RunningInterviewsButton = uibutton(app.GridLayout, 'push');
            app.RunningInterviewsButton.ButtonPushedFcn = createCallbackFcn(app, @RunninginterviewsNextPage, true);
            app.RunningInterviewsButton.Text = 'Running Interviews';
            app.LogoutButton = uibutton(app.GridLayout, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @logoutAdmin, true);
            app.LogoutButton.Text = 'Logout';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdminPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end