classdef AdminPageAddAdmin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        RemoveButton            matlab.ui.control.Button
        AddButton               matlab.ui.control.Button
        EnterPasswordEditField  matlab.ui.control.EditField
        EnterPasswordLabel      matlab.ui.control.Label
        MailIDEditField         matlab.ui.control.EditField
        MailIDEditFieldLabel    matlab.ui.control.Label
        NameEditField           matlab.ui.control.EditField
        NameEditFieldLabel      matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        Label                   matlab.ui.control.Label
        PreviousButton          matlab.ui.control.Button
        AddRemoveAdminLabel     matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    
    properties (Access = public)
        Password=""; % Description
        Username=""; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddAdminData(app, event)
            err=addAdmin(app.NameEditField.Value,app.MailIDEditField.Value,app.Password);
            if err==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="Admin with same name exists";
            else
                app.Label.FontColor=[0 1 0];
                app.Label.Text="The Admin has been added";
            end
        end

        % Value changing function: NameEditField
        function Removedisplay(app, event)
            app.Label.Text="";
        end

        % Button pushed function: PreviousButton
        function AddAdminPrevious(app, event)
            AdminPage;
            app.UIFigure.Visible="off";
        end

        % Value changed function: EnterPasswordEditField
        function PasswordCall(app, event)
            value = app.EnterPasswordEditField.Value;
            app.Password=value;
            x="";
            for i= 1:length(value)
                x=x+'*';
            end
            app.EnterPasswordEditField.Value=x;
        end

        % Button pushed function: RemoveButton
        function RemoveAdminData(app, event)
            if Username=="admin"
                if app.EnterPasswordEditField.Value=="admin"&&app.NameEditField.Value~="admin"
                    E=removeAdmin(app.NameEditField.Value);
                    if E==1
                        app.Label.FontColor=[1 0 0];
                        app.Label.Text="Admin has not been removed";
                    else
                        app.Label.FontColor=[0 1 0];
                        app.Label.Text="The Admin has been removed";
                    end
                else
                    app.Label.FontColor=[1 0 0];
                    app.Label.Text="Admin has not been removed";
                end
            else
                app.Label.FontColor=[1 0 0];
                app.Label.Text="Admin has not been removed";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.AddRemoveAdminLabel = uilabel(app.GridLayout);
            app.AddRemoveAdminLabel.Text = 'Add/Remove Admin';
            app.PreviousButton = uibutton(app.GridLayout, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @AddAdminPrevious, true);
            app.PreviousButton.Text = 'Previous';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.NameEditFieldLabel = uilabel(app.GridLayout);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.GridLayout, 'text');
            app.NameEditField.ValueChangingFcn = createCallbackFcn(app, @Removedisplay, true);
            app.MailIDEditFieldLabel = uilabel(app.GridLayout);
            app.MailIDEditFieldLabel.Text = 'Mail ID:';
            app.MailIDEditField = uieditfield(app.GridLayout, 'text');
            app.EnterPasswordLabel = uilabel(app.GridLayout);
            app.EnterPasswordLabel.Text = 'Enter Password:';
            app.EnterPasswordEditField = uieditfield(app.GridLayout, 'text');
            app.EnterPasswordEditField.ValueChangedFcn = createCallbackFcn(app, @PasswordCall, true);
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddAdminData, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.GridLayout, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAdminData, true);
            app.RemoveButton.Text = 'Remove';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdminPageAddAdmin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end