classdef AdminPageCandidatedetails < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        SearchButton_2                matlab.ui.control.Button
        EntertheCollegeNameEditField  matlab.ui.control.EditField
        EntertheCollegeNameEditFieldLabel  matlab.ui.control.Label
        EntertheNameEditField         matlab.ui.control.EditField
        EntertheNameEditFieldLabel    matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        PreviousButton                matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        SearchButton                  matlab.ui.control.Button
        CandidateDetailsLabel         matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchButton
        function GetCandidateDetails(app, event)
            [E,data] = getCandidateDetail(app.EntertheNameEditField.Value);
            if E==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="No data found";
            else
                app.Label.Text="";
                if isempty(data)==1
                    app.UITable.Data=[];
                    app.Label.FontColor=[1 0 0];
                    app.Label.Text="Data is empty";
                else
                    app.Label.Text="";
                    app.UITable.Data=data;
                end
            end
        end

        % Button pushed function: PreviousButton
        function CandidateDetailsPrevious(app, event)
            AdminPage;
            app.UIFigure.Visible="off";
        end

        % Button pushed function: SearchButton_2
        function CollegeNameSearch(app, event)
            [E,data] = getCollegeDetails(app.EntertheCollegeNameEditField.Value);
            if E==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="No data found";
            else
                app.Label.Text="";
                if isempty(data)==1
                    app.UITable.Data=[];
                    app.Label.FontColor=[1 0 0];
                    app.Label.Text="Data is empty";
                else
                    app.Label.FontColor=[0 1 0];
                    app.Label.Text="Successfully Added";
                    app.UITable.Data=data;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.CandidateDetailsLabel = uilabel(app.GridLayout);
            app.CandidateDetailsLabel.Text = 'Candidate Details';
            app.SearchButton = uibutton(app.GridLayout, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @GetCandidateDetails, true);
            app.SearchButton.Text = 'Search';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Name'; 'Unique Id'; 'Status'; 'Round 1'; 'Round 2'; 'Round 3'; 'Domain'; 'Mail ID'};
            app.UITable.RowName = {};
            app.PreviousButton = uibutton(app.GridLayout, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @CandidateDetailsPrevious, true);
            app.PreviousButton.Text = 'Previous';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.EntertheNameEditFieldLabel = uilabel(app.GridLayout);
            app.EntertheNameEditFieldLabel.Text = 'Enter the Name';
            app.EntertheNameEditField = uieditfield(app.GridLayout, 'text');
            app.EntertheCollegeNameEditFieldLabel = uilabel(app.GridLayout);
            app.EntertheCollegeNameEditFieldLabel.Text = 'Enter the College Name';
            app.EntertheCollegeNameEditField = uieditfield(app.GridLayout, 'text');
            app.SearchButton_2 = uibutton(app.GridLayout, 'push');
            app.SearchButton_2.ButtonPushedFcn = createCallbackFcn(app, @CollegeNameSearch, true);
            app.SearchButton_2.Text = 'Search';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.CandidateDetailsLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdminPageCandidatedetails

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end