classdef AdminPageEvaluation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SelectRoundDropDown             matlab.ui.control.DropDown
        SelectRoundDropDownLabel        matlab.ui.control.Label
        EntertheUniqueIdEditField       matlab.ui.control.EditField
        EntertheUniqueIdEditFieldLabel  matlab.ui.control.Label
        EntertheNameEditField           matlab.ui.control.EditField
        EntertheNameEditFieldLabel      matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        PreviousButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        NOGOButton                      matlab.ui.control.Button
        GOButton                        matlab.ui.control.Button
        EvaluationLabel                 matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GOButton
        function GoClick(app, event)
            app.Label.Text="";
            if app.SelectRoundDropDown.Value== "HR Round"
                Round="Round3";
            elseif app.SelectRoundDropDown.Value=="Technical Round"
                Round="Round1";
            else
                Round="Round2";
            end
            err=updateEvaluation(app.EntertheNameEditField.Value,app.EntertheUniqueIdEditField.Value,Round,"Go");
            if err==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="No records Found";
            else
                app.Label.FontColor=[0 1 0];
                app.Label.Text="Evaluation has been updated";
            end
        end

        % Button pushed function: NOGOButton
        function NogoClick(app, event)
            app.Label.Text="";
            if app.SelectRoundDropDown.Value== "HR Round"
                Round="Round3";
            elseif app.SelectRoundDropDown.Value=="Technical Round"
                Round="Round1";
            else
                Round="Round2";
            end 
            err=updateEvaluation(app.EntertheNameEditField.Value,app.EntertheUniqueIdEditField.Value,Round,"No Go");
            if err==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="No records Found";
            else
                app.Label.FontColor=[0 1 0];
                app.Label.Text="Evaluation has been updated";
            end
        end

        % Button pushed function: PreviousButton
        function EvaluationPrevious(app, event)
            AdminPage;
            app.UIFigure.Visible="off";
        end

        % Value changing function: EntertheNameEditField
        function NameChange(app, event)
            app.Label.Text="";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.EvaluationLabel = uilabel(app.GridLayout);
            app.EvaluationLabel.Text = 'Evaluation';
            app.GOButton = uibutton(app.GridLayout, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GoClick, true);
            app.GOButton.Text = 'GO';
            app.NOGOButton = uibutton(app.GridLayout, 'push');
            app.NOGOButton.ButtonPushedFcn = createCallbackFcn(app, @NogoClick, true);
            app.NOGOButton.Text = 'NOGO';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.PreviousButton = uibutton(app.GridLayout, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluationPrevious, true);
            app.PreviousButton.Text = 'Previous';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.EntertheNameEditFieldLabel = uilabel(app.GridLayout);
            app.EntertheNameEditFieldLabel.Text = 'Enter the Name';
            app.EntertheNameEditField = uieditfield(app.GridLayout, 'text');
            app.EntertheNameEditField.ValueChangingFcn = createCallbackFcn(app, @NameChange, true);
            app.EntertheUniqueIdEditFieldLabel = uilabel(app.GridLayout);
            app.EntertheUniqueIdEditFieldLabel.Text = 'Enter the Unique Id';
            app.EntertheUniqueIdEditField = uieditfield(app.GridLayout, 'text');
            app.SelectRoundDropDownLabel = uilabel(app.GridLayout);
            app.SelectRoundDropDownLabel.Text = 'Select Round';
            app.SelectRoundDropDown = uidropdown(app.GridLayout);
            app.SelectRoundDropDown.Items = {'HR Round', 'Technical Round', 'Managerial Round'};
            app.SelectRoundDropDown.Value = 'HR Round';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdminPageEvaluation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end