classdef AdminPageRunninginterviews < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        SelectEndTimeDropDown         matlab.ui.control.DropDown
        SelectEndTimeDropDownLabel    matlab.ui.control.Label
        SelectStartTimeDropDown       matlab.ui.control.DropDown
        SelectStartTimeDropDownLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        PreviousButton                matlab.ui.control.Button
        SearchButton                  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        RunningInterviewsLabel        matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchButton
        function SearchRunningInterviews(app, event)
            startTime = app.SelectStartTimeDropDown;
            endTime = app.SelectEndTimeDropDown;
            if str2num(startTime.Value) > str2num(endTime.Value)
                app.Label.FontColor=[1 0 0];
                app.Label.Text="Starting time is greater than ending time! Please check!";
                return;
            end
            [E,data] = getOngoingInterviews(startTime.Value,endTime.Value);
            if E==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="No Data Found";
            else
                if isempty(data)
                    app.Label.FontColor=[1 0 0];
                    app.Label.Text="No running interviews in this time frame!";
                else
                    app.Label.Text="";
                    app.UITable.Data=data;
                end
            end
        end

        % Button pushed function: PreviousButton
        function RunningInterviewsPrevious(app, event)
            AdminPage;
            app.UIFigure.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.RunningInterviewsLabel = uilabel(app.GridLayout);
            app.RunningInterviewsLabel.Text = 'Running Interviews';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Interviewer Name'; 'Candidate Name'; 'Start Time'; 'End Time'};
            app.UITable.RowName = {};
            app.SearchButton = uibutton(app.GridLayout, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchRunningInterviews, true);
            app.SearchButton.Text = 'Search';
            app.PreviousButton = uibutton(app.GridLayout, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @RunningInterviewsPrevious, true);
            app.PreviousButton.Text = 'Previous';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.SelectStartTimeDropDownLabel = uilabel(app.GridLayout);
            app.SelectStartTimeDropDownLabel.Text = 'Select Start Time';
            app.SelectStartTimeDropDown = uidropdown(app.GridLayout);
            app.SelectStartTimeDropDown.Items = {'900', '930', '1000', '1030', '1100', '1130', '1200', '1230', '1300', '1330', '1400', '1430', '1500', '1530', '1600', '1630', '1700', '1730', '1800'};
            app.SelectStartTimeDropDown.Value = '900';
            app.SelectEndTimeDropDownLabel = uilabel(app.GridLayout);
            app.SelectEndTimeDropDownLabel.Text = 'Select End Time';
            app.SelectEndTimeDropDown = uidropdown(app.GridLayout);
            app.SelectEndTimeDropDown.Items = {'900', '930', '1000', '1030', '1100', '1130', '1200', '1230', '1300', '1330', '1400', '1430', '1500', '1530', '1600', '1630', '1700', '1730', '1800'};
            app.SelectEndTimeDropDown.Value = '900';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdminPageRunninginterviews

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end