classdef InterviewerAddAvailableDate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        PickDateDatePicker         matlab.ui.control.DatePicker
        PickDateDatePickerLabel    matlab.ui.control.Label
        PreviousPageButton         matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        SubmitButton               matlab.ui.control.Button
        AddYourAvailableDateLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        InterviewerName string % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function AddAvailable(app, event)
            var=string(app.PickDateDatePicker.Value);
            %var=strrep(var,'-',':');
            
            %display(var);
            err=getFreeInterviewers(app.InterviewerName,var)
            err2=addDate(var)
             if err==1 | err2==1
                 app.Label.FontColor=[1 0 0];
                app.Label.Text="Couldn't update the date";
             else
                 app.Label.FontColor=[0 1 0];
                app.Label.Text="Updated";
            end
        end

        % Button pushed function: PreviousPageButton
        function PreviousPageButtonPushed(app, event)
            obj=InterviewerPage;
            obj.InterviewerName = app.InterviewerName;
            obj
            app.UIFigure.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.AddYourAvailableDateLabel = uilabel(app.GridLayout);
            app.AddYourAvailableDateLabel.Text = 'Add Your Available Date';
            app.SubmitButton = uibutton(app.GridLayout, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @AddAvailable, true);
            app.SubmitButton.Text = 'Submit';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.PreviousPageButton = uibutton(app.GridLayout, 'push');
            app.PreviousPageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousPageButtonPushed, true);
            app.PreviousPageButton.Text = 'Previous Page';
            app.PickDateDatePickerLabel = uilabel(app.GridLayout);
            app.PickDateDatePickerLabel.Text = 'Pick Date';
            app.PickDateDatePicker = uidatepicker(app.GridLayout);
            app.PickDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterviewerAddAvailableDate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end