classdef InterviewerPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LogoutButton               matlab.ui.control.Button
        CandidateDetailsButton     matlab.ui.control.Button
        Image2                     matlab.ui.control.Image
        AvailableDateButton        matlab.ui.control.Button
        AddReviewForStudentButton  matlab.ui.control.Button
        UpdateYourStatusButton     matlab.ui.control.Button
        ScheduledInterviewsButton  matlab.ui.control.Button
        InterviewerDashboardLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        InterviewerName string % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScheduledInterviewsButton
        function InterviewerScheduledInterviewsNextPage(app, event)
            
           
           [E,data] = getScheduledInterviews(app.InterviewerName);
           obj =  InterviewerScheduledInterviews;
           obj.InterviewerName = app.InterviewerName;
           obj.Label.Text="";
           if E==1
                obj.Label.Text="No data found";
           else
               
                if isempty(data)==1
                    obj.UITable.Data=[];
                    obj.Label.Text="Data is empty";
                else
                    obj.Label.Text="";
                    obj.UITable.Data=data;
                end
                obj
            end
           
           
           app.UIFigure.Visible="off"
        end

        % Button pushed function: UpdateYourStatusButton
        function InterviewerUpdateStatusNextPage(app, event)
            obj=InterviewerUpdateStatus;
            obj.InterviewerName=app.InterviewerName;
            obj
            app.UIFigure.Visible="off"
        end

        % Button pushed function: AddReviewForStudentButton
        function InterviewerAddReviewNextPage(app, event)
            obj=InterviewerAddReview;
            obj.InterviewerName=app.InterviewerName;
            app.UIFigure.Visible="off";
        end

        % Button pushed function: AvailableDateButton
        function InterviewerAvailableDateNextPage(app, event)
                obj=InterviewerAddAvailableDate;
                obj.InterviewerName=app.InterviewerName;
                obj
                app.UIFigure.Visible="off"
        end

        % Button pushed function: CandidateDetailsButton
        function CandidateDetailsNextPage(app, event)
            
            objj = InterviewerAllCandidatesDetails;
            objj.InterviewerName=app.InterviewerName;
            [E,objj.UITable.Data] = getAllCandidates();
            if E==1
                objj.Label.FontColor=[1 0 0];
                objj.Label.Text="No data available";
            else
                objj.Label.FontColor=[0 1 0];
                objj.Label.Text="";
            end
            objj
            app.UIFigure.Visible="off";
        end

        % Button pushed function: LogoutButton
        function LogoutInterviewer(app, event)
            app.UIFigure.Visible="off";
            LoginPage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.InterviewerDashboardLabel = uilabel(app.GridLayout);
            app.InterviewerDashboardLabel.Text = 'Interviewer Dashboard';
            app.ScheduledInterviewsButton = uibutton(app.GridLayout, 'push');
            app.ScheduledInterviewsButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerScheduledInterviewsNextPage, true);
            app.ScheduledInterviewsButton.Text = 'Scheduled Interviews';
            app.UpdateYourStatusButton = uibutton(app.GridLayout, 'push');
            app.UpdateYourStatusButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerUpdateStatusNextPage, true);
            app.UpdateYourStatusButton.Text = 'Update Your Status';
            app.AddReviewForStudentButton = uibutton(app.GridLayout, 'push');
            app.AddReviewForStudentButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerAddReviewNextPage, true);
            app.AddReviewForStudentButton.Text = 'Add Review For Student ';
            app.AvailableDateButton = uibutton(app.GridLayout, 'push');
            app.AvailableDateButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerAvailableDateNextPage, true);
            app.AvailableDateButton.Text = 'Available Date';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.CandidateDetailsButton = uibutton(app.GridLayout, 'push');
            app.CandidateDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @CandidateDetailsNextPage, true);
            app.CandidateDetailsButton.Text = 'Candidate Details';
            app.LogoutButton = uibutton(app.GridLayout, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutInterviewer, true);
            app.LogoutButton.Text = 'Logout';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterviewerPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end