classdef InterviewerUpdateStatus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        Label                  matlab.ui.control.Label
        PreviousPageButton     matlab.ui.control.Button
        Image2                 matlab.ui.control.Image
        UnavailableButton      matlab.ui.control.Button
        InterviewingButton     matlab.ui.control.Button
        IdleButton             matlab.ui.control.Button
        UpdateYourStatusLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
    end

    
    properties (Access = public)
        InterviewerName string % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IdleButton
        function InterviewerupdateIdle(app, event)
           err= updateStatus(app.InterviewerName,"Idle");
            if err==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="Couldn't update status";
            else
                app.Label.FontColor=[0 1 0];
                app.Label.Text="Status has been updated ";
            end
        end

        % Button pushed function: InterviewingButton
        function UpdateStatusInterviewing(app, event)
            err=updateStatus(app.InterviewerName,"Interviewing");
            if err==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="Couldn't update status";
            else
                app.Label.FontColor=[0 1 0];
                app.Label.Text="Status has been updated ";
            end
        end

        % Button pushed function: UnavailableButton
        function UpdateStatusUnavailable(app, event)
            err=updateStatus(app.InterviewerName,"Unavailable");
            if err==1
                app.Label.FontColor=[1 0 0];
                app.Label.Text="Couldn't update status";
            else
                app.Label.FontColor=[0 1 0];
                app.Label.Text="Status has been updated ";
            end
        end

        % Button pushed function: PreviousPageButton
        function PreviousPageButtonPushed(app, event)
            obj=InterviewerPage;
            obj.InterviewerName = app.InterviewerName;
            obj
            app.UIFigure.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.UpdateYourStatusLabel = uilabel(app.GridLayout);
            app.UpdateYourStatusLabel.Text = 'Update Your Status';
            app.IdleButton = uibutton(app.GridLayout, 'push');
            app.IdleButton.ButtonPushedFcn = createCallbackFcn(app, @InterviewerupdateIdle, true);
            app.IdleButton.Text = 'Idle ';
            app.InterviewingButton = uibutton(app.GridLayout, 'push');
            app.InterviewingButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateStatusInterviewing, true);
            app.InterviewingButton.Text = 'Interviewing ';
            app.UnavailableButton = uibutton(app.GridLayout, 'push');
            app.UnavailableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateStatusUnavailable, true);
            app.UnavailableButton.Text = 'Unavailable';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.PreviousPageButton = uibutton(app.GridLayout, 'push');
            app.PreviousPageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousPageButtonPushed, true);
            app.PreviousPageButton.Text = 'Previous Page';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterviewerUpdateStatus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end