classdef LoginPageSignUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        PreviousButton                 matlab.ui.control.Button
        ConfirmPasswordEditField       matlab.ui.control.EditField
        ConfirmPasswordEditFieldLabel  matlab.ui.control.Label
        NewPasswordEditField           matlab.ui.control.EditField
        NewPasswordEditFieldLabel      matlab.ui.control.Label
        MailIDEditField                matlab.ui.control.EditField
        MailIDEditFieldLabel           matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        DomainDropDown                 matlab.ui.control.DropDown
        DomainDropDownLabel            matlab.ui.control.Label
        DepartmentDropDown             matlab.ui.control.DropDown
        DepartmentDropDownLabel        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        SignUpButton                   matlab.ui.control.Button
        SignUpLabel                    matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        NewPassword=""; % Description
        ConfirmPassword="";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SignUpButton
        function SignUpNextPage(app, event)
            if strcmp(app.NewPassword,app.ConfirmPassword)
                if app.DepartmentDropDown.Value=="Human Resource"
                    Dept = "HR";
                elseif app.DepartmentDropDown.Value=="Technical"
                    Dept = "TECH";
                else
                    Dept = "MG";
                end
                E=signup(app.NameEditField.Value,Dept,app.DomainDropDown.Value,app.MailIDEditField.Value,app.NewPassword);
                if E==1
                    app.Label.FontColor=[1 0 0];
                    app.Label.Text="Interviewer with same name is already registered";
                else
                    app.Label.FontColor=[0 1 0];
                    app.Label.Text="You are registered";
                    app.UIFigure.Visible="off";
                    LoginPage
                end
            else
                app.Label.FontColor=[1 0 0];
                app.Label.Text="Passwords are not same";
                app.ConfirmPasswordEditField.Value="";
                app.NewPasswordEditField.Value="";
                app.ConfirmPassword="";
                app.NewPassword="";
            end
            
        end

        % Value changed function: DepartmentDropDown
        function ChangeDomainaccDepartment(app, event)
            value = app.DepartmentDropDown.Value;
            if value == "Human Resource"
                app.DomainDropDown.Items={'NA'};
            elseif value== "Manager"
                app.DomainDropDown.Items={'NA'};
            else
                app.DomainDropDown.Items={'Computer Science','Electronics','Electrical','Embedded System','Control System','VLSI','Signal Processing&Communication'};
            end
        end

        % Value changing function: NameEditField
        function NameChnage(app, event)
           app.Label.Text="";
        end

        % Value changed function: NewPasswordEditField
        function EncoderNewPassword(app, event)
            value = app.NewPasswordEditField.Value;
            app.NewPassword=value;
            x="";
            for i= 1:length(value)
                x=x+'*';
            end
            app.NewPasswordEditField.Value=x;
        end

        % Value changed function: ConfirmPasswordEditField
        function EncoderConfirmPassword(app, event)
            value = app.ConfirmPasswordEditField.Value;
            app.ConfirmPassword=value;
            x="";
            for i= 1:length(value)
                x=x+'*';
            end
            app.ConfirmPasswordEditField.Value=x;
        end

        % Button pushed function: PreviousButton
        function PreviousSignUp(app, event)
            app.UIFigure.Visible="off";
            LoginPage;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Picture1.png');
            app.SignUpLabel = uilabel(app.GridLayout);
            app.SignUpLabel.Text = 'Sign Up';
            app.SignUpButton = uibutton(app.GridLayout, 'push');
            app.SignUpButton.ButtonPushedFcn = createCallbackFcn(app, @SignUpNextPage, true);
            app.SignUpButton.Text = 'Sign Up';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'matlogo.png');
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.DepartmentDropDownLabel = uilabel(app.GridLayout);
            app.DepartmentDropDownLabel.Text = 'Department';
            app.DepartmentDropDown = uidropdown(app.GridLayout);
            app.DepartmentDropDown.Items = {'<Select>', 'Human Resource', 'Technical', 'Manager'};
            app.DepartmentDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeDomainaccDepartment, true);
            app.DepartmentDropDown.Value = '<Select>';
            app.DomainDropDownLabel = uilabel(app.GridLayout);
            app.DomainDropDownLabel.Text = 'Domain';
            app.DomainDropDown = uidropdown(app.GridLayout);
            app.DomainDropDown.Items = {'<Select>'};
            app.DomainDropDown.Value = '<Select>';
            app.NameEditFieldLabel = uilabel(app.GridLayout);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout, 'text');
            app.NameEditField.ValueChangingFcn = createCallbackFcn(app, @NameChnage, true);
            app.MailIDEditFieldLabel = uilabel(app.GridLayout);
            app.MailIDEditFieldLabel.Text = 'Mail ID';
            app.MailIDEditField = uieditfield(app.GridLayout, 'text');
            app.NewPasswordEditFieldLabel = uilabel(app.GridLayout);
            app.NewPasswordEditFieldLabel.Text = 'New Password';
            app.NewPasswordEditField = uieditfield(app.GridLayout, 'text');
            app.NewPasswordEditField.ValueChangedFcn = createCallbackFcn(app, @EncoderNewPassword, true);
            app.ConfirmPasswordEditFieldLabel = uilabel(app.GridLayout);
            app.ConfirmPasswordEditFieldLabel.Text = 'Confirm Password';
            app.ConfirmPasswordEditField = uieditfield(app.GridLayout, 'text');
            app.ConfirmPasswordEditField.ValueChangedFcn = createCallbackFcn(app, @EncoderConfirmPassword, true);
            app.PreviousButton = uibutton(app.GridLayout, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousSignUp, true);
            app.PreviousButton.Text = 'Previous';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginPageSignUp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end